classdef tichphan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConBoCon                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        HomeMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        nghiemTab                       matlab.ui.container.Tab
        ChnPhngPhpTmNghimButtonGroup    matlab.ui.container.ButtonGroup
        togglebutton_tab1               matlab.ui.control.ToggleButton
        timNgiemTuyenTinhButton         matlab.ui.control.ToggleButton
        timNghiemLapButton              matlab.ui.control.ToggleButton
        timNghiemChiaDoiButton          matlab.ui.control.ToggleButton
        nhapSaiSoTimNghiem              matlab.ui.control.NumericEditField
        SaischophpLabel                 matlab.ui.control.Label
        nhapPhuongTrinhTimNghiem        matlab.ui.control.EditField
        NhpphngtrnhLabel                matlab.ui.control.Label
        nhapKhoangPhanLyNghiem          matlab.ui.control.EditField
        KhongphnlynghimLabel            matlab.ui.control.Label
        kqSoLanLap                      matlab.ui.control.TextArea
        SLnLpTextAreaLabel              matlab.ui.control.Label
        kqNghiem                        matlab.ui.control.TextArea
        NghimCaPhngTrnhTextAreaLabel    matlab.ui.control.Label
        kqChon                          matlab.ui.control.TextArea
        ChnTextAreaLabel                matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        doThiHSNghiem                   matlab.ui.control.UIAxes
        noiSuyTab                       matlab.ui.container.Tab
        ChnPhngPhpNiSuyButtonGroup      matlab.ui.container.ButtonGroup
        togglebutton_tab2               matlab.ui.control.ToggleButton
        noiSuyNewton                    matlab.ui.control.ToggleButton
        noiSuyLarange                   matlab.ui.control.ToggleButton
        NhpgitrcnnisuyEditField         matlab.ui.control.NumericEditField
        NhpgitrcnnisuyEditFieldLabel_2  matlab.ui.control.Label
        nhapYNoiSuy                     matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_2        matlab.ui.control.Label
        nhapXNoiSuy                     matlab.ui.control.EditField
        NhpdliuxEditFieldLabel_2        matlab.ui.control.Label
        kqNoiSuy                        matlab.ui.control.TextArea
        KtqunisuyTextAreaLabel          matlab.ui.control.Label
        kqDaThucNoiSuy                  matlab.ui.control.TextArea
        KtquathcnisuyTextAreaLabel      matlab.ui.control.Label
        doThiHSNoiSuy                   matlab.ui.control.UIAxes
        hoiQuyTab                       matlab.ui.container.Tab
        ChnPhngPhpHiQuyButtonGroup      matlab.ui.container.ButtonGroup
        togglebutton_tab2_3             matlab.ui.control.ToggleButton
        hoiQuyLogarit                   matlab.ui.control.ToggleButton
        hoiQuyHamMu                     matlab.ui.control.ToggleButton
        hoiQuyTuyenTinh                 matlab.ui.control.ToggleButton
        nhapYHoiQuy                     matlab.ui.control.EditField
        NhpdliuyEditFieldLabel          matlab.ui.control.Label
        nhapXHoiQuy                     matlab.ui.control.EditField
        NhpdliuxEditFieldLabel          matlab.ui.control.Label
        kqHoiQuy                        matlab.ui.control.TextArea
        KtqudonLabel                    matlab.ui.control.Label
        KtquphngtrnhhiquyLabel          matlab.ui.control.Label
        kqPTHoiQuy                      matlab.ui.control.TextArea
        nhapGiaTriHoiQuy                matlab.ui.control.EditField
        NhpgitrcndonLabel               matlab.ui.control.Label
        doThiHSHoiQuy                   matlab.ui.control.UIAxes
        daoHamTab                       matlab.ui.container.Tab
        ChnPhngPhpoHmButtonGroup        matlab.ui.container.ButtonGroup
        togglebutton_tab4               matlab.ui.control.ToggleButton
        xapXiTrungTam                   matlab.ui.control.ToggleButton
        xapXiLui                        matlab.ui.control.ToggleButton
        xapXiTien                       matlab.ui.control.ToggleButton
        kqDaoHam                        matlab.ui.control.TextArea
        KtquohmLabel                    matlab.ui.control.Label
        nhapGiatriDaoHam                matlab.ui.control.NumericEditField
        NhpgitrtnhohmLabel              matlab.ui.control.Label
        chonSaiSoDaoHamDropDown         matlab.ui.control.DropDown
        ChngitrsaisDropDownLabel        matlab.ui.control.Label
        nhapBuocHDaoHam                 matlab.ui.control.NumericEditField
        NhpbctnhhLabel                  matlab.ui.control.Label
        chonDuLieuDaoHamButtonGroup     matlab.ui.container.ButtonGroup
        nhapHamSoDaoHam                 matlab.ui.control.EditField
        NhpHmSLabel                     matlab.ui.control.Label
        chonDuLieuDaoHam                matlab.ui.control.DropDown
        ChndliuvoLabel                  matlab.ui.control.Label
        nhapYDaoHam                     matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_3        matlab.ui.control.Label
        nhapXDaoHam                     matlab.ui.control.EditField
        NhpdliuxEditFieldLabel_3        matlab.ui.control.Label
        tichPhanTab                     matlab.ui.container.Tab
        ChnPhngPhpTchPhnButtonGroup     matlab.ui.container.ButtonGroup
        simpSon38                       matlab.ui.control.ToggleButton
        simpSon13                       matlab.ui.control.ToggleButton
        hinhThang                       matlab.ui.control.ToggleButton
        kqTichPhan                      matlab.ui.control.TextArea
        KtquohmLabel_2                  matlab.ui.control.Label
        nhapCanDuoiTichPhan             matlab.ui.control.NumericEditField
        NhpcndiLabel                    matlab.ui.control.Label
        nhapCanTrenTichPhan             matlab.ui.control.NumericEditField
        NhpcntrnLabel                   matlab.ui.control.Label
        nhapNTichPhan                   matlab.ui.control.NumericEditField
        NhpsonNLabel                    matlab.ui.control.Label
        chonDuLieuTichPhanButtonGroup   matlab.ui.container.ButtonGroup
        nhapHamSoTichPhan               matlab.ui.control.EditField
        NhpHmSLabel_2                   matlab.ui.control.Label
        chonDuLieuTichPhan              matlab.ui.control.DropDown
        ChndliuvoLabel_2                matlab.ui.control.Label
        nhapYTichPhan                   matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_4        matlab.ui.control.Label
        nhapXTichPhan                   matlab.ui.control.EditField
        NhpdliuxEditFieldLabel_4        matlab.ui.control.Label
        GioiThieuNhomTab                matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        FFFFFF10TeamLabel               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: chonDuLieuTichPhan
        function chonDuLieuTichPhanValueChanged(app, event)
            value = app.chonDuLieuTichPhan.Value;
            if (value == "Dữ liệu x,y")
                app.nhapXTichPhan.Enable = "on";
                app.nhapYTichPhan.Enable = "on";
                app.nhapHamSoTichPhan.Enable = "off";
                app.nhapCanDuoiTichPhan.Enable = "off";
                app.nhapCanTrenTichPhan.Enable = "off";
                app.nhapNTichPhan.Enable = "off";
            elseif (value == "Hàm f(x)")
                app.nhapXTichPhan.Enable = "off";
                app.nhapYTichPhan.Enable = "off";
                app.nhapHamSoTichPhan.Enable = "on";
                app.nhapCanDuoiTichPhan.Enable = "on";
                app.nhapCanTrenTichPhan.Enable = "on";
                app.nhapNTichPhan.Enable = "on";
            end
        end

        % Selection changed function: ChnPhngPhpTchPhnButtonGroup
        function ChnPhngPhpTchPhnButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChnPhngPhpTchPhnButtonGroup.SelectedObject;
            value_fx = app.nhapHamSoTichPhan.Value;
            value_chonDuLieu = app.chonDuLieuTichPhan.Value;
            value_canTren = app.nhapCanTrenTichPhan.Value;
            value_canDuoi = app.nhapCanDuoiTichPhan.Value;
            value_N = app.nhapNTichPhan.Value;
            string_x = app.nhapXTichPhan.Value;
            string_y = app.nhapYTichPhan.Value;
            
            fx = str2func(['@(x)',value_fx]);
            
            if (selectedButton == app.hinhThang)
                if(value_chonDuLieu == "Dữ liệu x,y")
                    string_x = strrep(string_x,',',' ');
                    string_x = strrep(string_x,'[','');
                    string_x = strrep(string_x,']','');
                    string_y = strrep(string_y,',',' ');
                    string_y = strrep(string_y,'[','');
                    string_y = strrep(string_y,']','');

                    x_temp = split(string_x);
                    y_temp = split(string_y);
                    x = str2double(x_temp);
                    y = str2double(y_temp);
                    result = 0;
                    for i = 2:1:length(x)
                        result = result + (y(i) + y(i - 1)) * (x(i) - x(i-1)) / 2;
                    end
                    app.kqTichPhan.Value = string(result);
                elseif (value_chonDuLieu == "Hàm f(x)")
                    result = tichphanhinhthang(fx,value_canDuoi,value_canTren,value_N);
                    app.kqTichPhan.Value = string(result);
                end
            elseif (selectedButton == app.simpSon13)
                if(value_chonDuLieu == "Dữ liệu x,y")
                    app.kqTichPhan.Value = "dữ liệu đầu vào cần phải là một hàm";
                elseif (value_chonDuLieu == "Hàm f(x)")
                    if mod(value_N,2) == 0
                        result = tichphanSimpson13(fx,value_canDuoi,value_canTren,value_N);
                        app.kqTichPhan.Value = string(result);
                    else
                        app.kqTichPhan.Value = "điều kiện không thỏa, N phải là số chẵn";
                    end
                end
            elseif (selectedButton == app.simpSon38)
                if(value_chonDuLieu == "Dữ liệu x,y")
                    app.kqTichPhan.Value = "dữ liệu đầu vào cần phải là một hàm";
                elseif (value_chonDuLieu == "Hàm f(x)")
                    if mod(value_N,3) == 0
                        result = tichphanSimpson38(fx,value_canDuoi,value_canTren,value_N);
                        app.kqTichPhan.Value = string(result);
                    else
                        app.kqTichPhan.Value = "điều kiện không thỏa, N phải chia hết cho 3";
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConBoCon = uifigure('Visible', 'off');
            app.ConBoCon.Name = 'UI Figure';
            app.FileMenu = uimenu(app.ConBoCon);
            app.FileMenu.Text = 'File';
            app.HomeMenu = uimenu(app.ConBoCon);
            app.HomeMenu.Text = 'Home';
            app.TabGroup = uitabgroup(app.ConBoCon);
            app.nghiemTab = uitab(app.TabGroup);
            app.nghiemTab.Title = 'Nghiệm';
            app.doThiHSNghiem = uiaxes(app.nghiemTab);
            title(app.doThiHSNghiem, 'ĐỒ THỊ HÀM SỐ')
            xlabel(app.doThiHSNghiem, 'x')
            ylabel(app.doThiHSNghiem, 'y')
            app.Label = uilabel(app.nghiemTab);
            app.Label.Text = '-------------------------------------------------------------------------------------------------------------------------------------------------------------------';
            app.ChnTextAreaLabel = uilabel(app.nghiemTab);
            app.ChnTextAreaLabel.Text = 'Đã Chọn';
            app.kqChon = uitextarea(app.nghiemTab);
            app.kqChon.Editable = 'off';
            app.NghimCaPhngTrnhTextAreaLabel = uilabel(app.nghiemTab);
            app.NghimCaPhngTrnhTextAreaLabel.Text = 'Nghiệm Của Phương Trình';
            app.kqNghiem = uitextarea(app.nghiemTab);
            app.kqNghiem.Editable = 'off';
            app.SLnLpTextAreaLabel = uilabel(app.nghiemTab);
            app.SLnLpTextAreaLabel.Text = 'Số Lần Lặp';
            app.kqSoLanLap = uitextarea(app.nghiemTab);
            app.kqSoLanLap.Editable = 'off';
            app.KhongphnlynghimLabel = uilabel(app.nghiemTab);
            app.KhongphnlynghimLabel.Text = 'Khoảng phân ly nghiệm';
            app.nhapKhoangPhanLyNghiem = uieditfield(app.nghiemTab, 'text');
            app.NhpphngtrnhLabel = uilabel(app.nghiemTab);
            app.NhpphngtrnhLabel.Text = 'Nhập phương trình';
            app.nhapPhuongTrinhTimNghiem = uieditfield(app.nghiemTab, 'text');
            app.SaischophpLabel = uilabel(app.nghiemTab);
            app.SaischophpLabel.Text = 'Sai số cho phép';
            app.nhapSaiSoTimNghiem = uieditfield(app.nghiemTab, 'numeric');
            app.ChnPhngPhpTmNghimButtonGroup = uibuttongroup(app.nghiemTab);
            app.ChnPhngPhpTmNghimButtonGroup.Title = 'Chọn Phương Pháp Tìm Nghiệm';
            app.timNghiemChiaDoiButton = uitogglebutton(app.ChnPhngPhpTmNghimButtonGroup);
            app.timNghiemChiaDoiButton.Text = 'Chia Đôi';
            app.timNghiemLapButton = uitogglebutton(app.ChnPhngPhpTmNghimButtonGroup);
            app.timNghiemLapButton.Text = 'Lặp';
            app.timNgiemTuyenTinhButton = uitogglebutton(app.ChnPhngPhpTmNghimButtonGroup);
            app.timNgiemTuyenTinhButton.Text = 'Newton ( Tuyến tính)';
            app.togglebutton_tab1 = uitogglebutton(app.ChnPhngPhpTmNghimButtonGroup);
            app.togglebutton_tab1.Enable = 'off';
            app.togglebutton_tab1.Visible = 'off';
            app.togglebutton_tab1.Text = '';
            app.togglebutton_tab1.Value = true;
            app.noiSuyTab = uitab(app.TabGroup);
            app.noiSuyTab.Title = 'Nội Suy';
            app.doThiHSNoiSuy = uiaxes(app.noiSuyTab);
            title(app.doThiHSNoiSuy, 'ĐỒ THỊ HÀM SỐ')
            xlabel(app.doThiHSNoiSuy, 'X')
            ylabel(app.doThiHSNoiSuy, 'Y')
            app.KtquathcnisuyTextAreaLabel = uilabel(app.noiSuyTab);
            app.KtquathcnisuyTextAreaLabel.Text = 'Kết quả đa thức nội suy';
            app.kqDaThucNoiSuy = uitextarea(app.noiSuyTab);
            app.kqDaThucNoiSuy.Editable = 'off';
            app.KtqunisuyTextAreaLabel = uilabel(app.noiSuyTab);
            app.KtqunisuyTextAreaLabel.Text = 'Kết quả nội suy';
            app.kqNoiSuy = uitextarea(app.noiSuyTab);
            app.kqNoiSuy.Editable = 'off';
            app.NhpdliuxEditFieldLabel_2 = uilabel(app.noiSuyTab);
            app.NhpdliuxEditFieldLabel_2.Text = 'Nhập dữ liệu x';
            app.nhapXNoiSuy = uieditfield(app.noiSuyTab, 'text');
            app.NhpdliuyEditFieldLabel_2 = uilabel(app.noiSuyTab);
            app.NhpdliuyEditFieldLabel_2.Text = 'Nhập dữ liệu y';
            app.nhapYNoiSuy = uieditfield(app.noiSuyTab, 'text');
            app.NhpgitrcnnisuyEditFieldLabel_2 = uilabel(app.noiSuyTab);
            app.NhpgitrcnnisuyEditFieldLabel_2.Text = 'Nhập giá trị cần nội suy';
            app.NhpgitrcnnisuyEditField = uieditfield(app.noiSuyTab, 'numeric');
            app.ChnPhngPhpNiSuyButtonGroup = uibuttongroup(app.noiSuyTab);
            app.ChnPhngPhpNiSuyButtonGroup.Title = 'Chọn Phương Pháp Nội Suy';
            app.noiSuyLarange = uitogglebutton(app.ChnPhngPhpNiSuyButtonGroup);
            app.noiSuyLarange.Text = 'Nội Suy Larange';
            app.noiSuyNewton = uitogglebutton(app.ChnPhngPhpNiSuyButtonGroup);
            app.noiSuyNewton.Text = 'Nội Suy Newton';
            app.togglebutton_tab2 = uitogglebutton(app.ChnPhngPhpNiSuyButtonGroup);
            app.togglebutton_tab2.Enable = 'off';
            app.togglebutton_tab2.Visible = 'off';
            app.togglebutton_tab2.Text = '';
            app.togglebutton_tab2.Value = true;
            app.hoiQuyTab = uitab(app.TabGroup);
            app.hoiQuyTab.Title = 'Hồi Quy';
            app.doThiHSHoiQuy = uiaxes(app.hoiQuyTab);
            title(app.doThiHSHoiQuy, 'ĐỒ THỊ HÀM SỐ')
            xlabel(app.doThiHSHoiQuy, 'X')
            ylabel(app.doThiHSHoiQuy, 'Y')
            app.NhpgitrcndonLabel = uilabel(app.hoiQuyTab);
            app.NhpgitrcndonLabel.Text = 'Nhập giá trị cần dự đoán';
            app.nhapGiaTriHoiQuy = uieditfield(app.hoiQuyTab, 'text');
            app.kqPTHoiQuy = uitextarea(app.hoiQuyTab);
            app.kqPTHoiQuy.Editable = 'off';
            app.KtquphngtrnhhiquyLabel = uilabel(app.hoiQuyTab);
            app.KtquphngtrnhhiquyLabel.Text = 'Kết quả phương trình hồi quy';
            app.KtqudonLabel = uilabel(app.hoiQuyTab);
            app.KtqudonLabel.Text = 'Kết quả dự đoán';
            app.kqHoiQuy = uitextarea(app.hoiQuyTab);
            app.kqHoiQuy.Editable = 'off';
            app.NhpdliuxEditFieldLabel = uilabel(app.hoiQuyTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.nhapXHoiQuy = uieditfield(app.hoiQuyTab, 'text');
            app.NhpdliuyEditFieldLabel = uilabel(app.hoiQuyTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.nhapYHoiQuy = uieditfield(app.hoiQuyTab, 'text');
            app.ChnPhngPhpHiQuyButtonGroup = uibuttongroup(app.hoiQuyTab);
            app.ChnPhngPhpHiQuyButtonGroup.Title = 'Chọn Phương Pháp Hồi Quy';
            app.hoiQuyTuyenTinh = uitogglebutton(app.ChnPhngPhpHiQuyButtonGroup);
            app.hoiQuyTuyenTinh.Text = 'Hồi Quy Tuyến Tính';
            app.hoiQuyHamMu = uitogglebutton(app.ChnPhngPhpHiQuyButtonGroup);
            app.hoiQuyHamMu.Text = 'Hồi Quy Hàm Mũ';
            app.hoiQuyLogarit = uitogglebutton(app.ChnPhngPhpHiQuyButtonGroup);
            app.hoiQuyLogarit.Text = 'Hồi Quy Logarit';
            app.togglebutton_tab2_3 = uitogglebutton(app.ChnPhngPhpHiQuyButtonGroup);
            app.togglebutton_tab2_3.Enable = 'off';
            app.togglebutton_tab2_3.Visible = 'off';
            app.togglebutton_tab2_3.Text = '';
            app.togglebutton_tab2_3.Value = true;
            app.daoHamTab = uitab(app.TabGroup);
            app.daoHamTab.Title = 'Đạo Hàm';
            app.chonDuLieuDaoHamButtonGroup = uibuttongroup(app.daoHamTab);
            app.chonDuLieuDaoHamButtonGroup.Title = 'Chọn kiểu dữ liệu vào';
            app.NhpdliuxEditFieldLabel_3 = uilabel(app.chonDuLieuDaoHamButtonGroup);
            app.NhpdliuxEditFieldLabel_3.Text = 'Nhập dữ liệu x ';
            app.nhapXDaoHam = uieditfield(app.chonDuLieuDaoHamButtonGroup, 'text');
            app.NhpdliuyEditFieldLabel_3 = uilabel(app.chonDuLieuDaoHamButtonGroup);
            app.NhpdliuyEditFieldLabel_3.Text = 'Nhập dữ liệu y';
            app.nhapYDaoHam = uieditfield(app.chonDuLieuDaoHamButtonGroup, 'text');
            app.ChndliuvoLabel = uilabel(app.chonDuLieuDaoHamButtonGroup);
            app.ChndliuvoLabel.Text = 'Chọn dữ liệu vào';
            app.chonDuLieuDaoHam = uidropdown(app.chonDuLieuDaoHamButtonGroup);
            app.chonDuLieuDaoHam.Items = {'Dữ liệu x,y', 'Hàm f(x)'};
            app.chonDuLieuDaoHam.Value = 'Dữ liệu x,y';
            app.NhpHmSLabel = uilabel(app.chonDuLieuDaoHamButtonGroup);
            app.NhpHmSLabel.Enable = 'off';
            app.NhpHmSLabel.Text = 'Nhập Hàm Số';
            app.nhapHamSoDaoHam = uieditfield(app.chonDuLieuDaoHamButtonGroup, 'text');
            app.nhapHamSoDaoHam.Enable = 'off';
            app.NhpbctnhhLabel = uilabel(app.daoHamTab);
            app.NhpbctnhhLabel.Text = 'Nhập bước tính h';
            app.nhapBuocHDaoHam = uieditfield(app.daoHamTab, 'numeric');
            app.ChngitrsaisDropDownLabel = uilabel(app.daoHamTab);
            app.ChngitrsaisDropDownLabel.Text = 'Chọn giá trị sai số';
            app.chonSaiSoDaoHamDropDown = uidropdown(app.daoHamTab);
            app.chonSaiSoDaoHamDropDown.Items = {'O(h)', 'O(h^2)'};
            app.chonSaiSoDaoHamDropDown.Value = 'O(h)';
            app.NhpgitrtnhohmLabel = uilabel(app.daoHamTab);
            app.NhpgitrtnhohmLabel.Text = 'Nhập giá trị tính đạo hàm ';
            app.nhapGiatriDaoHam = uieditfield(app.daoHamTab, 'numeric');
            app.KtquohmLabel = uilabel(app.daoHamTab);
            app.KtquohmLabel.Text = 'Kết quả đạo hàm';
            app.kqDaoHam = uitextarea(app.daoHamTab);
            app.kqDaoHam.Editable = 'off';
            app.ChnPhngPhpoHmButtonGroup = uibuttongroup(app.daoHamTab);
            app.ChnPhngPhpoHmButtonGroup.Title = 'Chọn Phương Pháp Đạo Hàm';
            app.xapXiTien = uitogglebutton(app.ChnPhngPhpoHmButtonGroup);
            app.xapXiTien.Text = 'Xấp Xỉ Tiến';
            app.xapXiLui = uitogglebutton(app.ChnPhngPhpoHmButtonGroup);
            app.xapXiLui.Text = 'Xấp Xỉ Lùi';
            app.xapXiTrungTam = uitogglebutton(app.ChnPhngPhpoHmButtonGroup);
            app.xapXiTrungTam.Text = 'Xấp Xỉ Trung Tâm';
            app.togglebutton_tab4 = uitogglebutton(app.ChnPhngPhpoHmButtonGroup);
            app.togglebutton_tab4.Enable = 'off';
            app.togglebutton_tab4.Visible = 'off';
            app.togglebutton_tab4.Text = '';
            app.togglebutton_tab4.Value = true;
            app.tichPhanTab = uitab(app.TabGroup);
            app.tichPhanTab.Title = 'Tích Phân';
            app.chonDuLieuTichPhanButtonGroup = uibuttongroup(app.tichPhanTab);
            app.chonDuLieuTichPhanButtonGroup.Title = 'Chọn kiểu dữ liệu vào';
            app.NhpdliuxEditFieldLabel_4 = uilabel(app.chonDuLieuTichPhanButtonGroup);
            app.NhpdliuxEditFieldLabel_4.Text = 'Nhập dữ liệu x ';
            app.nhapXTichPhan = uieditfield(app.chonDuLieuTichPhanButtonGroup, 'text');
            app.NhpdliuyEditFieldLabel_4 = uilabel(app.chonDuLieuTichPhanButtonGroup);
            app.NhpdliuyEditFieldLabel_4.Text = 'Nhập dữ liệu y';
            app.nhapYTichPhan = uieditfield(app.chonDuLieuTichPhanButtonGroup, 'text');
            app.ChndliuvoLabel_2 = uilabel(app.chonDuLieuTichPhanButtonGroup);
            app.ChndliuvoLabel_2.Text = 'Chọn dữ liệu vào';
            app.chonDuLieuTichPhan = uidropdown(app.chonDuLieuTichPhanButtonGroup);
            app.chonDuLieuTichPhan.Items = {'Dữ liệu x,y', 'Hàm f(x)'};
            app.chonDuLieuTichPhan.ValueChangedFcn = createCallbackFcn(app, @chonDuLieuTichPhanValueChanged, true);
            app.chonDuLieuTichPhan.Value = 'Dữ liệu x,y';
            app.NhpHmSLabel_2 = uilabel(app.chonDuLieuTichPhanButtonGroup);
            app.NhpHmSLabel_2.Text = 'Nhập Hàm Số';
            app.nhapHamSoTichPhan = uieditfield(app.chonDuLieuTichPhanButtonGroup, 'text');
            app.nhapHamSoTichPhan.Enable = 'off';
            app.nhapHamSoTichPhan.Value = '0';
            app.NhpsonNLabel = uilabel(app.tichPhanTab);
            app.NhpsonNLabel.Text = 'Nhập số đoạn N';
            app.nhapNTichPhan = uieditfield(app.tichPhanTab, 'numeric');
            app.nhapNTichPhan.Enable = 'off';
            app.NhpcntrnLabel = uilabel(app.tichPhanTab);
            app.NhpcntrnLabel.Text = 'Nhập cận trên';
            app.nhapCanTrenTichPhan = uieditfield(app.tichPhanTab, 'numeric');
            app.nhapCanTrenTichPhan.Enable = 'off';
            app.NhpcndiLabel = uilabel(app.tichPhanTab);
            app.NhpcndiLabel.Text = 'Nhập cận dưới';
            app.nhapCanDuoiTichPhan = uieditfield(app.tichPhanTab, 'numeric');
            app.nhapCanDuoiTichPhan.Enable = 'off';
            app.KtquohmLabel_2 = uilabel(app.tichPhanTab);
            app.KtquohmLabel_2.Text = 'Kết quả tích phân';
            app.kqTichPhan = uitextarea(app.tichPhanTab);
            app.kqTichPhan.Editable = 'off';
            app.ChnPhngPhpTchPhnButtonGroup = uibuttongroup(app.tichPhanTab);
            app.ChnPhngPhpTchPhnButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChnPhngPhpTchPhnButtonGroupSelectionChanged, true);
            app.ChnPhngPhpTchPhnButtonGroup.Title = 'Chọn Phương Pháp Tích Phân';
            app.hinhThang = uitogglebutton(app.ChnPhngPhpTchPhnButtonGroup);
            app.hinhThang.Text = 'Hình Thang';
            app.hinhThang.Value = true;
            app.simpSon13 = uitogglebutton(app.ChnPhngPhpTchPhnButtonGroup);
            app.simpSon13.Text = 'Simpson 1/3';
            app.simpSon38 = uitogglebutton(app.ChnPhngPhpTchPhnButtonGroup);
            app.simpSon38.Text = 'Simpson 3/8';
            app.GioiThieuNhomTab = uitab(app.TabGroup);
            app.GioiThieuNhomTab.Title = 'Giới Thiệu Nhóm';
            app.FFFFFF10TeamLabel = uilabel(app.GioiThieuNhomTab);
            app.FFFFFF10TeamLabel.Text = 'FFFFFF10 Team';
            app.UITable = uitable(app.GioiThieuNhomTab);
            app.UITable.ColumnName = {'STT'; 'MSSV'; 'Họ và Tên'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false false];
            app.UITable.SelectionType = 'row';
            % Show the figure after all components are created
            app.ConBoCon.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tichphan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConBoCon)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConBoCon)
        end
    end
end